classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GenerateButton             matlab.ui.control.Button
        OutputMethodButtonGroup    matlab.ui.container.ButtonGroup
        CSVFileButton              matlab.ui.control.RadioButton
        CHeaderFileButton          matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
        PlotButton                 matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LowpassTab                 matlab.ui.container.Tab
        FcEditFieldLabel           matlab.ui.control.Label
        LowPassFcEditField         matlab.ui.control.NumericEditField
        bLabel                     matlab.ui.control.Label
        LowpassStopbandEditField   matlab.ui.control.NumericEditField
        FsLabel                    matlab.ui.control.Label
        LowPassFsEditField         matlab.ui.control.NumericEditField
        HighpassTab                matlab.ui.container.Tab
        FcEditField_2Label         matlab.ui.control.Label
        HighPassFcEditField        matlab.ui.control.NumericEditField
        bLabel_2                   matlab.ui.control.Label
        HighPassStopbandEditField  matlab.ui.control.NumericEditField
        FsLabel_2                  matlab.ui.control.Label
        HighPassFsEditField        matlab.ui.control.NumericEditField
        BandpassTab                matlab.ui.container.Tab
        FaEditFieldLabel           matlab.ui.control.Label
        BandPassFaEditField        matlab.ui.control.NumericEditField
        bLabel_3                   matlab.ui.control.Label
        BandpassStopbandEditField  matlab.ui.control.NumericEditField
        FbEditFieldLabel           matlab.ui.control.Label
        BandPassFbEditField        matlab.ui.control.NumericEditField
        FsLabel_3                  matlab.ui.control.Label
        BandPassFsEditField        matlab.ui.control.NumericEditField
        BandstopTab                matlab.ui.container.Tab
        FaEditField_2Label         matlab.ui.control.Label
        BandStopFaEditField        matlab.ui.control.NumericEditField
        bLabel_4                   matlab.ui.control.Label
        BandStopStopbandEditField  matlab.ui.control.NumericEditField
        FbEditField_2Label         matlab.ui.control.Label
        BandStopFbEditField        matlab.ui.control.NumericEditField
        FsLabel_4                  matlab.ui.control.Label
        BandStopFsEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        selectedTab
        fs 
        outputSelection 
        fc
        b
        fa
        fb
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.selectedTab = app.TabGroup.SelectedTab;
            app.outputSelection = app.OutputMethodButtonGroup.SelectedObject;
        end

        % Value changed function: LowPassFcEditField
        function LowPassFcEditFieldValueChanged(app, event)
            app.fc = app.LowPassFcEditField.Value;
        end

        % Value changed function: LowpassStopbandEditField
        function LowpassStopbandEditFieldValueChanged(app, event)
            app.b = app.LowpassStopbandEditField.Value;
        end

        % Value changed function: HighPassFcEditField
        function HighPassFcEditFieldValueChanged(app, event)
            app.fc = app.HighPassFcEditField.Value;
        end

        % Value changed function: HighPassStopbandEditField
        function HighPassStopbandEditFieldValueChanged(app, event)
            app.b = app.HighPassStopbandEditField.Value;
        end

        % Value changed function: BandPassFaEditField
        function BandPassFaEditFieldValueChanged(app, event)
            app.fa = app.BandPassFaEditField.Value;
        end

        % Value changed function: BandpassStopbandEditField
        function BandpassStopbandEditFieldValueChanged(app, event)
            app.b = app.BandpassStopbandEditField.Value;
        end

        % Value changed function: BandPassFbEditField
        function BandPassFbEditFieldValueChanged(app, event)
            app.fb = app.BandPassFbEditField.Value;
        end

        % Value changed function: BandStopFaEditField
        function BandStopFaEditFieldValueChanged(app, event)
            app.fa = app.BandStopFaEditField.Value;
        end

        % Value changed function: BandStopStopbandEditField
        function BandStopStopbandEditFieldValueChanged(app, event)
            app.b = app.BandStopStopbandEditField.Value;
        end

        % Value changed function: BandStopFbEditField
        function BandStopFbEditFieldValueChanged(app, event)
            app.fb = app.BandStopFbEditField.Value;
        end

        % Selection changed function: OutputMethodButtonGroup
        function OutputMethodButtonGroupSelectionChanged(app, event)
            app.outputSelection = app.OutputMethodButtonGroup.SelectedObject;
        end

        % Value changed function: LowPassFsEditField
        function LowPassFsEditFieldValueChanged(app, event)
            app.fs = app.LowPassFsEditField.Value;
        end

        % Value changed function: HighPassFsEditField
        function HighPassFsEditFieldValueChanged(app, event)
            app.fs = app.HighPassFsEditField.Value;
        end

        % Value changed function: BandPassFsEditField
        function BandPassFsEditFieldValueChanged(app, event)
            app.fs = app.BandPassFsEditField.Value;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if(app.selectedTab == app.LowpassTab)
                h = generate_lowpass(app.fs, app.fc, app.b);
                [f, y_s] = spectrum_coef(h, app.fs);
                plot(app.UIAxes, f, y_s)
            elseif(app.selectedTab == app.HighpassTab)
                h = generate_highpass(app.fs, app.fc, app.b);
                [f, y_s] = spectrum_coef(h, app.fs);
                plot(app.UIAxes, f, y_s)
            elseif(app.selectedTab == app.BandpassTab)
                h = generate_bandpass(app.fs, app.fa, app.fb, app.b);
                [f, y_s] = spectrum_coef(h, app.fs);
                plot(app.UIAxes, f, y_s)
            elseif(app.selectedTab == app.BandstopTab)
                h = generate_bandstop(app.fs, app.fa, app.fb, app.b);
                [f, y_s] = spectrum_coef(h, app.fs);
                plot(app.UIAxes, f, y_s)
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if(app.selectedTab == app.LowpassTab)
                h = generate_lowpass(app.fs, app.fc, app.b);
                if(app.outputSelection == app.CSVFileButton)
                    csvwrite('filter.csv', h);
                elseif(app.outputSelection == app.CHeaderFileButton)
                    generate_c_header(h);
                end
            elseif(app.selectedTab == app.HighpassTab)
                h = generate_highpass(app.fs, app.fc, app.b);
                if(app.outputSelection == app.CSVFileButton)
                    csvwrite('filter.csv', h);
                elseif(app.outputSelection == app.CHeaderFileButton)
                    generate_c_header(h);
                end
            elseif(app.selectedTab == app.BandpassTab)
                h = generate_bandpass(app.fs, app.fa, app.fb, app.b);
                if(app.outputSelection == app.CSVFileButton)
                    csvwrite('filter.csv', h);
                elseif(app.outputSelection == app.CHeaderFileButton)
                    generate_c_header(h);
                end
            elseif(app.selectedTab == app.BandstopTab)
                h = generate_bandstop(app.fs, app.fa, app.fb, app.b);
                if(app.outputSelection == app.CSVFileButton)
                    csvwrite('filter.csv', h);
                elseif(app.outputSelection == app.CHeaderFileButton)
                    generate_c_header(h);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.OutputMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputMethodButtonGroupSelectionChanged, true);
            app.OutputMethodButtonGroup.Title = 'Output Method';
            app.CSVFileButton = uiradiobutton(app.OutputMethodButtonGroup);
            app.CSVFileButton.Text = 'CSV File';
            app.CSVFileButton.Value = true;
            app.CHeaderFileButton = uiradiobutton(app.OutputMethodButtonGroup);
            app.CHeaderFileButton.Text = 'C Header File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Filter Kernel')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LowpassTab = uitab(app.TabGroup);
            app.LowpassTab.Title = 'Lowpass';
            app.FcEditFieldLabel = uilabel(app.LowpassTab);
            app.FcEditFieldLabel.Text = 'Fc';
            app.LowPassFcEditField = uieditfield(app.LowpassTab, 'numeric');
            app.LowPassFcEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassFcEditFieldValueChanged, true);
            app.bLabel = uilabel(app.LowpassTab);
            app.bLabel.Text = 'b';
            app.LowpassStopbandEditField = uieditfield(app.LowpassTab, 'numeric');
            app.LowpassStopbandEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassStopbandEditFieldValueChanged, true);
            app.FsLabel = uilabel(app.LowpassTab);
            app.FsLabel.Text = 'Fs';
            app.LowPassFsEditField = uieditfield(app.LowpassTab, 'numeric');
            app.LowPassFsEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassFsEditFieldValueChanged, true);
            app.HighpassTab = uitab(app.TabGroup);
            app.HighpassTab.Title = 'Highpass';
            app.FcEditField_2Label = uilabel(app.HighpassTab);
            app.FcEditField_2Label.Text = 'Fc';
            app.HighPassFcEditField = uieditfield(app.HighpassTab, 'numeric');
            app.HighPassFcEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassFcEditFieldValueChanged, true);
            app.bLabel_2 = uilabel(app.HighpassTab);
            app.bLabel_2.Text = 'b';
            app.HighPassStopbandEditField = uieditfield(app.HighpassTab, 'numeric');
            app.HighPassStopbandEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassStopbandEditFieldValueChanged, true);
            app.FsLabel_2 = uilabel(app.HighpassTab);
            app.FsLabel_2.Text = 'Fs';
            app.HighPassFsEditField = uieditfield(app.HighpassTab, 'numeric');
            app.HighPassFsEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassFsEditFieldValueChanged, true);
            app.BandpassTab = uitab(app.TabGroup);
            app.BandpassTab.Title = 'Bandpass';
            app.FaEditFieldLabel = uilabel(app.BandpassTab);
            app.FaEditFieldLabel.Text = 'Fa';
            app.BandPassFaEditField = uieditfield(app.BandpassTab, 'numeric');
            app.BandPassFaEditField.ValueChangedFcn = createCallbackFcn(app, @BandPassFaEditFieldValueChanged, true);
            app.bLabel_3 = uilabel(app.BandpassTab);
            app.bLabel_3.Text = 'b';
            app.BandpassStopbandEditField = uieditfield(app.BandpassTab, 'numeric');
            app.BandpassStopbandEditField.ValueChangedFcn = createCallbackFcn(app, @BandpassStopbandEditFieldValueChanged, true);
            app.FbEditFieldLabel = uilabel(app.BandpassTab);
            app.FbEditFieldLabel.Text = 'Fb';
            app.BandPassFbEditField = uieditfield(app.BandpassTab, 'numeric');
            app.BandPassFbEditField.ValueChangedFcn = createCallbackFcn(app, @BandPassFbEditFieldValueChanged, true);
            app.FsLabel_3 = uilabel(app.BandpassTab);
            app.FsLabel_3.Text = 'Fs';
            app.BandPassFsEditField = uieditfield(app.BandpassTab, 'numeric');
            app.BandPassFsEditField.ValueChangedFcn = createCallbackFcn(app, @BandPassFsEditFieldValueChanged, true);
            app.BandstopTab = uitab(app.TabGroup);
            app.BandstopTab.Title = 'Bandstop';
            app.FaEditField_2Label = uilabel(app.BandstopTab);
            app.FaEditField_2Label.Text = 'Fa';
            app.BandStopFaEditField = uieditfield(app.BandstopTab, 'numeric');
            app.BandStopFaEditField.ValueChangedFcn = createCallbackFcn(app, @BandStopFaEditFieldValueChanged, true);
            app.bLabel_4 = uilabel(app.BandstopTab);
            app.bLabel_4.Text = 'b';
            app.BandStopStopbandEditField = uieditfield(app.BandstopTab, 'numeric');
            app.BandStopStopbandEditField.ValueChangedFcn = createCallbackFcn(app, @BandStopStopbandEditFieldValueChanged, true);
            app.FbEditField_2Label = uilabel(app.BandstopTab);
            app.FbEditField_2Label.Text = 'Fb';
            app.BandStopFbEditField = uieditfield(app.BandstopTab, 'numeric');
            app.BandStopFbEditField.ValueChangedFcn = createCallbackFcn(app, @BandStopFbEditFieldValueChanged, true);
            app.FsLabel_4 = uilabel(app.BandstopTab);
            app.FsLabel_4.Text = 'Fs';
            app.BandStopFsEditField = uieditfield(app.BandstopTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end